classdef ardadeneme2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Label2                        matlab.ui.control.Label
        RPMLabel                      matlab.ui.control.Label
        BPMLabel                      matlab.ui.control.Label
        LableLabel                    matlab.ui.control.Label
        OrderofFilterEditField        matlab.ui.control.NumericEditField
        OrderofFilterEditFieldLabel   matlab.ui.control.Label
        LowPassCutoffEditField        matlab.ui.control.NumericEditField
        LowPassCutoffEditFieldLabel   matlab.ui.control.Label
        HighPassCutoffEditField       matlab.ui.control.NumericEditField
        HighPassCutoffEditFieldLabel  matlab.ui.control.Label
        StopButton                    matlab.ui.control.Button
        StartButton                   matlab.ui.control.Button
        UIAxes_4                      matlab.ui.control.UIAxes
        UIAxes_3                      matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end


     properties (Access = private)
        a 
        f
        f1
        Y
        Y1
        P1
        P2
        P3
        P4
        filteredPlot
        rawPlot
        serialport
        Fs = 8000;
        fs = 5000;
        fc1 = 30;
        fc2 = 200;
        T = 1/5000;
        n = 1;
        m = 1;
        m1 = 1;
        plotWindow = 2000;
        IsRunning = false;
        Timer
        Data
        ffData
        fdata 
        coefficient_1
        coefficient_2
        fc3=30
        rpm
        bpm
        coefficient_3
        coefficient_4
        orderOfFilter=4;
        end

    methods (Access = private)


   function calculateRPM(app)
    % Sinyalin son penceresini alın
    signalWindow = app.a.UserData.Data(max(1, end-app.plotWindow+1):end); 
  
    % Pikleri bulun
    threshold = 510; % Pik algılama için eşik değeri
    [peaks, locs] = findpeaks(signalWindow, 'MinPeakHeight', threshold);

    % Zaman aralığını hesapla
    if length(locs) > 1
        timeIntervals = diff(locs) * app.T; % Pikler arasındaki zaman farkı (saniye)
        avgTimePerCycle = mean(timeIntervals); % Ortalama zaman farkı
        app.rpm = 10 / avgTimePerCycle; % RPM hesaplama

    else
        app.rpm = 0; % Yeterli pik yoksa RPM sıfırdır
    end
         %  etiketinLabel2i güncelle
    app.Label2.Text = sprintf('%.2f RPM', app.rpm);
   
end


        function timerCallback = func(app)
            if ~app.IsRunning
            stop(app.Timer);
        end
    end
 end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.IsRunning=true;
            app.Timer=timer;
            app.Timer.Period=0.03;
            app.Timer.ExecutionMode='fixedRate';
            app.Timer.TimerFcn=@(~,~)app.timerCallback;
            start(app.Timer);

            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.IsRunning = true;
           app.a =serialport ("COM8", 115200);
            configureTerminator(app.a, "CR/LF");
            flush(app.a);
            app.a.UserData = struct("Data", [], "Order", 1, "fData", [],"ffData",[]);
            app.IsRunning = true;
            
            while app.a.UserData.Order < 100000
                    app.Data = readline(app.a);
                    app.a.UserData.Data(end+1) = str2double(app.Data);
                    app.a.UserData.Order = app.a.UserData.Order + 1;
                if mod(app.a.UserData.Order, 10) == 0
                    configureCallback(app.a, "off");

                    plot(app.UIAxes, app.a.UserData.Data(max(1,end-app.plotWindow+1):end))
                      [app.coefficient_1, app.coefficient_2] = butter(4, app.fc3/(app.fs/2), 'high');
        app.a.UserData.ffData = filter(app.coefficient_1, app.coefficient_2, app.a.UserData.Data(1:end));
                    [app.coefficient_3, app.coefficient_4] = butter(app.orderOfFilter, [app.fc1/(app.fs/2), app.fc2/(app.fs/2)], 'bandpass');

                    app.a.UserData.fData = filter(app.coefficient_3, app.coefficient_4, app.a.UserData.Data);
                    
                    plot(app.UIAxes_2, app.a.UserData.fData(max(1, end-app.plotWindow+1):end) * 3);
                    drawnow;
                    
                    configureTerminator(app.a, "CR/LF");

                end
                 if mod(app.a.UserData.Order,500)==0
                     configureCallback(app.a, "off");

                     app.Y = fft(app.a.UserData.ffData(end-499+1:end));
                     app.P2 = abs(app.Y / 500); % Use 400 for normalization
                     app.f = app.fs * (0:(length(app.Y)/2))/length(app.Y); % Adjust frequency axis
                     app.P1 = app.P2(1:length(app.Y)/2+1);
                     app.P1(2:end-1) = 2 * app.P1(2:end-1);
                     plot(app.UIAxes_3,app.f,app.P1,'Color','r');
                     set(gca,'ylim',[0,10]);

                     app.Y1 = fft(app.a.UserData.fData(end-499+1:end));
                     app.P3 = abs(app.Y1 / 500); % Use 400 for normalization
                     app.f1 = app.fs * (0:(length(app.Y1)/2))/length(app.Y1); % Adjust frequency axis
                     app.P4 = app.P3(1:length(app.Y1)/2+1);
                     app.P4(2:end-1) = 2 * app.P4(2:end-1);
                     plot(app.UIAxes_4, app.f1,app.P4,'Color','r');
                     set(gca,'ylim',[0,15]);

                     grid on;
                     app.m=app.m+500;
                     configureTerminator(app.a,"CR/LF");
                 end
if mod(app.a.UserData.Order, 1500) == 0
    % Measure elapsed time
    timeAtPointBPM = toc;

    % Detect peaks for BPM
    thresholdBPM = 510; % BPM için eşik değeri
    [peaksBPM, ~] = findpeaks(app.a.UserData.Data(end-1499+1:end), 'MinPeakHeight', thresholdBPM);
    numPeaksBPM = numel(peaksBPM); % BPM için pik sayısı

    % Calculate BPM
    app.bpm = (10 * numPeaksBPM) / timeAtPointBPM; % BPM hesaplama
    disp(int16(app.bpm)); % Konsola yazdır
    app.LableLabel.Text = num2str(app.bpm); % GUI'de göster
    tic;

    % Detect peaks for RPM
    thresholdRPM = 510; % RPM için eşik değeri
    [peaksRPM, ~] = findpeaks(app.a.UserData.Data(end-1499+1:end), 'MinPeakHeight', thresholdRPM);
    numPeaksRPM = numel(peaksRPM); % RPM için pik sayısı

    if numPeaksRPM > 1
        % Zaman aralığını hesapla
        timeIntervals = diff(find(peaksRPM)) ; % Pikler arası zaman farkı
        avgTimePerCycle = mean(timeIntervals); % Ortalama zaman farkı
        app.rpm = 0.01 / avgTimePerCycle; % RPM hesapla
    else
        app.rpm = 0; % Yeterli pik yoksa RPM sıfırdır
    end

    % Display RPM
    disp(int16(app.rpm)); % Konsola yazdır
    app.Label2.Text = num2str(app.rpm); % GUI'de göster
end

                end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.IsRunning=false;
            delete(app.a);
        end

        % Value changed function: LowPassCutoffEditField
        function LowPassCutoffEditFieldValueChanged(app, event)
            b=app.LowPassCutoffEditField.Value;
            app.fc2=b;
        end

        % Value changed function: HighPassCutoffEditField
        function HighPassCutoffEditFieldValueChanged(app, event)
             c= app.HighPassCutoffEditField.Value;
             app.fc1=c;
        end

        % Value changed function: OrderofFilterEditField
        function OrderofFilterEditFieldValueChanged(app, event)
            j = app.OrderofFilterEditField.Value;
            app.orderOfFilter=j;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Raw-Signal (Oscilloscope)')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YLim = [-1000 1000];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Filtered-Signal')
            xlabel(app.UIAxes_2, 'Time')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.YLim = [-1000 1000];
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Raw-Signal FFT')
            xlabel(app.UIAxes_3, 'Frequency')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Filtered-Signal FFT')
            xlabel(app.UIAxes_4, 'Frequency')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.HighPassCutoffEditFieldLabel = uilabel(app.UIFigure);
            app.HighPassCutoffEditFieldLabel.Text = 'High-Pass Cutoff';
            app.HighPassCutoffEditField = uieditfield(app.UIFigure, 'numeric');
            app.HighPassCutoffEditField.ValueChangedFcn = createCallbackFcn(app, @HighPassCutoffEditFieldValueChanged, true);
            app.HighPassCutoffEditField.Value = 30;
            app.LowPassCutoffEditFieldLabel = uilabel(app.UIFigure);
            app.LowPassCutoffEditFieldLabel.Text = 'Low-Pass Cutoff';
            app.LowPassCutoffEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowPassCutoffEditField.ValueChangedFcn = createCallbackFcn(app, @LowPassCutoffEditFieldValueChanged, true);
            app.LowPassCutoffEditField.Value = 200;
            app.OrderofFilterEditFieldLabel = uilabel(app.UIFigure);
            app.OrderofFilterEditFieldLabel.Text = 'Order of Filter';
            app.OrderofFilterEditField = uieditfield(app.UIFigure, 'numeric');
            app.OrderofFilterEditField.ValueChangedFcn = createCallbackFcn(app, @OrderofFilterEditFieldValueChanged, true);
            app.OrderofFilterEditField.Value = 4;
            app.LableLabel = uilabel(app.UIFigure);
            app.LableLabel.Text = 'Lable';
            app.BPMLabel = uilabel(app.UIFigure);
            app.BPMLabel.Text = 'BPM';
            app.RPMLabel = uilabel(app.UIFigure);
            app.RPMLabel.Text = 'RPM';
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ardadeneme2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end